classdef SYNTH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        VolumeSlider            matlab.ui.control.Slider
        VolumeSliderLabel       matlab.ui.control.Label
        SawtoothAxes            matlab.ui.control.UIAxes
        SquareAxes              matlab.ui.control.UIAxes
        CenterPanel             matlab.ui.container.Panel
        MediaControlLabel       matlab.ui.control.Label
        PlayButton              matlab.ui.control.Button
        StopButton              matlab.ui.control.Button
        TimeAxes                matlab.ui.control.UIAxes
        FreqAxes                matlab.ui.control.UIAxes
        RightPanel              matlab.ui.container.Panel
        ImportFileButton        matlab.ui.control.Button
        FileNameEditField       matlab.ui.control.EditField
        FileNameEditFieldLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % originalWaveFileObj - object holding all information in original wav
    % currWaveData - object holding all information in new wave file
    properties (Access = private)

        originalWaveFileObj
        currWaveFileObj
        squareOscillator
        sawtoothOscillator
    end
    
    % All helper methods to handle gui interaction
    methods (Access = private)

        %{
            Initializes the square oscillator
        %}
        function initializeSquare(app, amplitude, freq, width, duration, fs)

            app.squareOscillator = generateSquareOscillator(amplitude, freq, width, duration, fs);
        end
        %{
            Initializes the square oscillator
        %}
        function initializeSawtooth(app, amplitude, freq,  duration, fs)
            
            app.sawtoothOscillator = generateSawtoothOscillator(amplitude, freq, duration, fs);
        end

        %{
            Plots oscillator type.

            @param axesHandle - axes reference
            @param audioData - data of audio (time domain)
        %}
        function loadOscillator(app, axesHandle, audioData)
            
            plot(axesHandle, audioData);
        end
        
        %{
            Initializes the .wav file object to be used

            @param filePath - the path of the file read in
        %}
        function loadWav(app, filePath)
        
            app.originalWaveFileObj = WaveFile(filePath);
            app.currWaveFileObj = app.originalWaveFileObj;
        end
        
        %{
            Plays sound of .wav file, from the beginning
        %}
        function playAudio(app)
         
            playSound(app.currWaveFileObj);    
        end

        %{
            Stops playing sound (if currently playing)
        %}
        function stopAudio(app)

            stopSound();   
        end

        %{
            Plots the two graphs of our data.
        %}
        function loadPlot(app)
            
            timeAxesHandle = app.TimeAxes;
            freqAxesHandle = app.FreqAxes;

            plot(timeAxesHandle, app.currWaveFileObj.timeData);
            ylim(timeAxesHandle, [-1, 1]);
            plot(freqAxesHandle, abs(app.currWaveFileObj.freqData));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initializeSquare(app, 1, 1, 0, 2, 44100);
            initializeSawtooth(app, 1, 1, 2, 44100);

            loadOscillator(app, app.SquareAxes, app.squareOscillator);
            loadOscillator(app, app.SawtoothAxes, app.sawtoothOscillator);
        end

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed(app, event)
            % read in data. ref:
            % https://www.mathworks.com/help/matlab/ref/uigetfile.html
            % https://www.mathworks.com/help/matlab/ref/fullfile.html
            
            [filename, path] = uigetfile('*.wav');
            app.FileNameEditField.Value = filename;

            loadWav(app, fullfile(path, filename));

            % load the plots whenever new file is uploaded
            loadPlot(app);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            
            playAudio(app);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
            stopAudio(app);
        end

        % Button down function: TimeAxes
        function TimeAxesButtonDown(app, event)

        end

        % Button down function: FreqAxes
        function FreqAxesButtonDown(app, event)
            
        end

        % Value changed function: VolumeSlider
        function VolumeSliderValueChanged(app, event)
            value = app.VolumeSlider.Value;

            app.currWaveFileObj = changeAmplitude(app.originalWaveFileObj, value/50);
            loadPlot(app);
        end

        % Value changing function: VolumeSlider
        function VolumeSliderValueChanging(app, event)
            changingValue = event.Value;
        end

        % Button down function: SquareAxes
        function SquareAxesButtonDown(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SquareAxes = uiaxes(app.LeftPanel);
            app.SquareAxes.XTick = [];
            app.SquareAxes.YTick = [];
            app.SquareAxes.ButtonDownFcn = createCallbackFcn(app, @SquareAxesButtonDown, true);
            app.SawtoothAxes = uiaxes(app.LeftPanel);
            app.SawtoothAxes.XTick = [];
            app.SawtoothAxes.YTick = [];
            app.VolumeSliderLabel = uilabel(app.LeftPanel);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.LeftPanel);
            app.VolumeSlider.MajorTickLabels = {'0', '20', '40', '60', '80', '100'};
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
            app.VolumeSlider.ValueChangingFcn = createCallbackFcn(app, @VolumeSliderValueChanging, true);
            app.VolumeSlider.Value = 50;
            app.CenterPanel = uipanel(app.GridLayout);
            app.FreqAxes = uiaxes(app.CenterPanel);
            title(app.FreqAxes, 'Frequency Waveform')
            xlabel(app.FreqAxes, 'Angular Frequency')
            ylabel(app.FreqAxes, 'Amplitude')
            zlabel(app.FreqAxes, 'Z')
            app.FreqAxes.XTick = [];
            app.FreqAxes.XTickLabel = '';
            app.FreqAxes.YTick = [];
            app.FreqAxes.YTickLabel = '';
            app.FreqAxes.ZTick = [];
            app.FreqAxes.ButtonDownFcn = createCallbackFcn(app, @FreqAxesButtonDown, true);
            app.TimeAxes = uiaxes(app.CenterPanel);
            title(app.TimeAxes, 'Audio Waveform')
            xlabel(app.TimeAxes, 'Time')
            ylabel(app.TimeAxes, 'Amplitude')
            zlabel(app.TimeAxes, 'Z')
            app.TimeAxes.XTick = [];
            app.TimeAxes.XTickLabel = '';
            app.TimeAxes.YTick = [];
            app.TimeAxes.YTickLabel = '';
            app.TimeAxes.ZTick = [];
            app.TimeAxes.ButtonDownFcn = createCallbackFcn(app, @TimeAxesButtonDown, true);
            app.StopButton = uibutton(app.CenterPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PlayButton = uibutton(app.CenterPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.MediaControlLabel = uilabel(app.CenterPanel);
            app.MediaControlLabel.Text = 'Media Control';
            app.RightPanel = uipanel(app.GridLayout);
            app.FileNameEditFieldLabel = uilabel(app.RightPanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.RightPanel, 'text');
            app.ImportFileButton = uibutton(app.RightPanel, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed, true);
            app.ImportFileButton.Text = 'Import File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SYNTH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end